classdef LSA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        Panel_output                 matlab.ui.container.Panel
        Panel_6                      matlab.ui.container.Panel
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        Panel_5                      matlab.ui.container.Panel
        StatusLabel                  matlab.ui.control.Label
        Panel_bode                   matlab.ui.container.Panel
        EditField_4                  matlab.ui.control.EditField
        EditField_4Label             matlab.ui.control.Label
        EditField_3                  matlab.ui.control.EditField
        Label_2                      matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        Panel_control                matlab.ui.container.Panel
        ClearButton                  matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        bodeButton                   matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        Tab_2                        matlab.ui.container.Tab
        Panel_output_2               matlab.ui.container.Panel
        Panel_8                      matlab.ui.container.Panel
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes2_2                    matlab.ui.control.UIAxes
        Panel_7                      matlab.ui.container.Panel
        Panel_bode_2                 matlab.ui.container.Panel
        SettlingTimeEditField        matlab.ui.control.EditField
        Label_7                      matlab.ui.control.Label
        OvershootingEditField        matlab.ui.control.EditField
        Label_6                      matlab.ui.control.Label
        PeakTimeEditField            matlab.ui.control.EditField
        Label_5                      matlab.ui.control.Label
        RiseTimeEditField            matlab.ui.control.EditField
        Label_4                      matlab.ui.control.Label
        UIAxes4                      matlab.ui.control.UIAxes
        Panel_control_2              matlab.ui.container.Panel
        SimulationTimeEditField      matlab.ui.control.EditField
        Label_3                      matlab.ui.control.Label
        UnitImpulseResponseButton    matlab.ui.control.Button
        UnitRampResponseButton       matlab.ui.control.Button
        UnitStepResponseButton       matlab.ui.control.Button
        Tab_3                        matlab.ui.container.Tab
        Panel_control_3              matlab.ui.container.Panel
        UnitImpulseResponseButton_3  matlab.ui.control.Button
        kEditField                   matlab.ui.control.EditField
        Label_8                      matlab.ui.control.Label
        UnitImpulseResponseButton_2  matlab.ui.control.Button
        UnitRampResponseButton_2     matlab.ui.control.Button
        UnitStepResponseButton_2     matlab.ui.control.Button
        Panel_output_3               matlab.ui.container.Panel
        Panel_10                     matlab.ui.container.Panel
        UIAxes3_3                    matlab.ui.control.UIAxes
        UIAxes2_3                    matlab.ui.control.UIAxes
        Panel_9                      matlab.ui.container.Panel
        StatusLabel2                 matlab.ui.control.Label
        UIAxes2_5                    matlab.ui.control.UIAxes
        UIAxes2_4                    matlab.ui.control.UIAxes
        Tab_4                        matlab.ui.container.Tab
        Panel_output_4               matlab.ui.container.Panel
        Panel_bode_3                 matlab.ui.container.Panel
        WcEditField                  matlab.ui.control.EditField
        Label_14                     matlab.ui.control.Label
        PMEditField                  matlab.ui.control.EditField
        Label_13                     matlab.ui.control.Label
        KcEditField                  matlab.ui.control.EditField
        Label_12                     matlab.ui.control.Label
        PEditField                   matlab.ui.control.EditField
        Label_11                     matlab.ui.control.Label
        ZEditField                   matlab.ui.control.EditField
        Label_10                     matlab.ui.control.Label
        GcEditField                  matlab.ui.control.EditField
        Label_9                      matlab.ui.control.Label
        StepAxes                     matlab.ui.control.UIAxes
        PhaseAxes                    matlab.ui.control.UIAxes
        MagnitudeAxes                matlab.ui.control.UIAxes
        Panel_control_4              matlab.ui.container.Panel
        TargetEssEditField           matlab.ui.control.EditField
        Label_15                     matlab.ui.control.Label
        TargetWcEditField            matlab.ui.control.EditField
        EditField_6Label             matlab.ui.control.Label
        TargetPMEditField            matlab.ui.control.EditField
        EditField_5Label             matlab.ui.control.Label
        UnitImpulseResponseButton_5  matlab.ui.control.Button
        SimTimeEditField             matlab.ui.control.EditField
        EditField_7Label             matlab.ui.control.Label
        UnitImpulseResponseButton_4  matlab.ui.control.Button
        Panel_2                      matlab.ui.container.Panel
        CleanButton                  matlab.ui.control.Button
        CreateButton                 matlab.ui.control.Button
        EditField_2                  matlab.ui.control.EditField
        EditField_2Label             matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        Label                        matlab.ui.control.Label
    end

    
    properties (Access = private)
        sys_tf
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            
            try
                num = str2num(app.EditField.Value);     % 分子
                den = str2num(app.EditField_2.Value);   % 分母
        
                app.sys_tf = tf(num, den);
        
                uialert(app.UIFigure, '系统创建成功', '提示');
            catch
                uialert(app.UIFigure, '系统参数输入有误', '错误');
            end
            
        end

        % Button pushed function: CleanButton
        function CleanButtonPushed(app, event)
            app.EditField.Value = '';
            app.EditField_2.Value = '';
        end

        % Button pushed function: bodeButton
        function bodeButtonPushed(app, event)
            try
                % ===== 使用总 App 的共用系统 =====
                if isempty(app.sys_tf)
                    app.StatusLabel.Text = '错误：请先创建系统';
                    return;
                end
                sys = app.sys_tf;
        
                app.StatusLabel.Text = '正在绘制伯德图...';
                drawnow;
        
                % ===== 计算伯德图数据 =====
                [mag, phase, w] = bode(sys);
        
                % 将数据从3D矩阵转换为向量
                mag = squeeze(mag);
                phase = squeeze(phase);
        
                % 转换为分贝（dB）
                mag_dB = 20*log10(mag);
        
                % ============ 计算稳定裕度 ============
                [Gm, Pm, Wcg, Wcp] = margin(sys);
        
                % 幅值裕度（转换为分贝）
                Gm_dB = 20*log10(Gm);
        
                % 处理特殊情况（如果系统稳定裕度无限大）
                if isinf(Gm_dB)
                    Gm_display = '无穷大';
                else
                    Gm_display = sprintf('%.2f dB', Gm_dB);
                end
        
                % 相位裕度
                if isinf(Pm)
                    Pm_display = '无穷大';
                else
                    Pm_display = sprintf('%.2f°', Pm);
                end
        
                % ============ 显示稳定裕度在EditField中 ============
                app.EditField_3.Value = Gm_display;  % 幅值裕度
                app.EditField_4.Value = Pm_display;  % 相位裕度
        
                % ============ 幅频特性图 - 绘制到UIAxes ============
                cla(app.UIAxes);
                semilogx(app.UIAxes, w, mag_dB, 'b-', 'LineWidth', 2);
        
                % 在幅频图上标记增益裕度（如果有限）
                if ~isinf(Gm_dB) && ~isinf(Wcg)
                    hold(app.UIAxes, 'on');
                    plot(app.UIAxes, [Wcg, Wcg], [-100, Gm_dB], 'g--', 'LineWidth', 1.5);
                    plot(app.UIAxes, [min(w), Wcg], [Gm_dB, Gm_dB], 'g--', 'LineWidth', 1.5);
                    text(app.UIAxes, Wcg, Gm_dB, sprintf('增益裕度: %.2f dB', Gm_dB), ...
                        'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', ...
                        'FontSize', 8, 'Color', 'g');
                    hold(app.UIAxes, 'off');
                end
        
                grid(app.UIAxes, 'on');
                ylabel(app.UIAxes, '幅值 (dB)', 'FontSize', 10);
                title(app.UIAxes, sprintf('伯德图 - 幅频特性 (增益裕度: %s)', Gm_display), 'FontSize', 12);
        
                % ============ 相频特性图 - 绘制到UIAxes_2 ============
                cla(app.UIAxes_2);
                semilogx(app.UIAxes_2, w, phase, 'r-', 'LineWidth', 2);
        
                if ~isinf(Pm) && ~isinf(Wcp)
                    hold(app.UIAxes_2, 'on');
                    plot(app.UIAxes_2, [Wcp, Wcp], [-360, Pm-180], 'g--', 'LineWidth', 1.5);
                    plot(app.UIAxes_2, [min(w), Wcp], [Pm-180, Pm-180], 'g--', 'LineWidth', 1.5);
                    text(app.UIAxes_2, Wcp, Pm-180, sprintf('相位裕度: %.2f°', Pm), ...
                        'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', ...
                        'FontSize', 8, 'Color', 'g');
                    hold(app.UIAxes_2, 'off');
                end
        
                grid(app.UIAxes_2, 'on');
                xlabel(app.UIAxes_2, '频率 (rad/sec)', 'FontSize', 10);
                ylabel(app.UIAxes_2, '相位 (度)', 'FontSize', 10);
                title(app.UIAxes_2, sprintf('伯德图 - 相频特性 (相位裕度: %s)', Pm_display), 'FontSize', 12);
        
                % ===== 更新状态信息 =====
                if isinf(Gm_dB) && isinf(Pm)
                    app.StatusLabel.Text = '伯德图绘制完成（系统非常稳定）';
                elseif isinf(Gm_dB)
                    app.StatusLabel.Text = sprintf('伯德图绘制完成（增益裕度无穷大，相位裕度: %.2f°）', Pm);
                elseif isinf(Pm)
                    app.StatusLabel.Text = sprintf('伯德图绘制完成（增益裕度: %.2f dB，相位裕度无穷大）', Gm_dB);
                else
                    app.StatusLabel.Text = sprintf('伯德图绘制完成（增益裕度: %.2f dB @ %.2f rad/s，相位裕度: %.2f° @ %.2f rad/s）', ...
                        Gm_dB, Wcg, Pm, Wcp);
                end
        
            catch ME
                app.StatusLabel.Text = sprintf('错误：%s', ME.message);
                msgbox(sprintf('发生错误：%s', ME.message), '错误', 'error');
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            try
                % ===== 使用总 App 的共用系统 =====
                if isempty(app.sys_tf)
                    app.StatusLabel.Text = '错误：请先创建系统';
                    return;
                end
                sys = app.sys_tf;

                app.StatusLabel.Text = '正在绘制奈奎斯特图...';
                drawnow;

                % ============ 计算稳定裕度 ============
                [Gm, Pm, Wcg, Wcp] = margin(sys);

                % 幅值裕度（转换为分贝）
                Gm_dB = 20*log10(Gm);

                % 处理特殊情况（如果系统稳定裕度无限大）
                if isinf(Gm_dB)
                    Gm_display = '无穷大';
                else
                    Gm_display = sprintf('%.2f dB', Gm_dB);
                end

                % 相位裕度
                if isinf(Pm)
                    Pm_display = '无穷大';
                else
                    Pm_display = sprintf('%.2f°', Pm);
                end

                % ============ 显示稳定裕度在EditField中 ============
                app.EditField_3.Value = Gm_display;  % 幅值裕度
                app.EditField_4.Value = Pm_display;  % 相位裕度

                % ============ 计算奈奎斯特数据 ============
                [re, im, w_nyq] = nyquist(sys);

                % 将数据从3D矩阵转换为向量
                re = squeeze(re);
                im = squeeze(im);

                % ============ 绘制奈奎斯特图到UIAxes2 ============
                cla(app.UIAxes2);

                % 绘制奈奎斯特曲线
                plot(app.UIAxes2, re, im, 'b-', 'LineWidth', 2);
                hold(app.UIAxes2, 'on');

                % 绘制负实轴参考线（从0到-∞）
                plot(app.UIAxes2, [-1e6, 0], [0, 0], 'r--', 'LineWidth', 1);

                % 标记临界点 (-1, 0)
                plot(app.UIAxes2, -1, 0, 'ro', 'MarkerSize', 10, 'MarkerFaceColor', 'r');

                % 标记增益裕度对应的点（如果存在）
                if ~isinf(Gm_dB) && ~isinf(Wcg)
                    % 计算增益裕度对应的奈奎斯特点
                    idx = find(w_nyq >= Wcg, 1, 'first');
                    if ~isempty(idx) && idx <= length(re)
                        plot(app.UIAxes2, re(idx), im(idx), 'go', 'MarkerSize', 10, 'MarkerFaceColor', 'g');
                        text(app.UIAxes2, re(idx), im(idx), ...
                            sprintf('增益裕度点\n(%.2f, %.2f)', re(idx), im(idx)), ...
                            'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', ...
                            'FontSize', 8, 'Color', 'g');

                        % 绘制从原点到该点的连线
                        plot(app.UIAxes2, [0, re(idx)], [0, im(idx)], 'g--', 'LineWidth', 1);

                        % 显示增益裕度距离信息
                        text(app.UIAxes2, re(idx)/2, im(idx)/2, ...
                            sprintf('增益裕度: %.2f dB', Gm_dB), ...
                            'VerticalAlignment', 'middle', 'HorizontalAlignment', 'center', ...
                            'FontSize', 8, 'Color', 'g');
                    end
                end

                % 标记相位裕度对应的点（如果存在）
                if ~isinf(Pm) && ~isinf(Wcp)
                    % 计算相位裕度对应的奈奎斯特点
                    idx = find(w_nyq >= Wcp, 1, 'first');
                    if ~isempty(idx) && idx <= length(re)
                        % 计算该点的极坐标形式
                        mag_point = sqrt(re(idx)^2 + im(idx)^2);
                        phase_point = atan2(im(idx), re(idx)) * 180/pi;

                        plot(app.UIAxes2, re(idx), im(idx), 'mo', 'MarkerSize', 10, 'MarkerFaceColor', 'm');
                        text(app.UIAxes2, re(idx), im(idx), ...
                            sprintf('相位裕度点\n|G|=%.2f, ∠G=%.1f°', mag_point, phase_point), ...
                            'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', ...
                            'FontSize', 8, 'Color', 'm');

                        % 绘制从原点到该点的连线
                        plot(app.UIAxes2, [0, re(idx)], [0, im(idx)], 'm--', 'LineWidth', 1);

                        % 显示相位裕度信息
                        text(app.UIAxes2, re(idx)/2, im(idx)/2, ...
                            sprintf('相位裕度: %.1f°', Pm), ...
                            'VerticalAlignment', 'middle', 'HorizontalAlignment', 'center', ...
                            'FontSize', 8, 'Color', 'm');
                    end
                end

                % 绘制单位圆（有助于理解相位裕度）
                theta = linspace(0, 2*pi, 100);
                unit_circle_x = cos(theta);
                unit_circle_y = sin(theta);
                plot(app.UIAxes2, unit_circle_x, unit_circle_y, 'k:', 'LineWidth', 0.5);

                hold(app.UIAxes2, 'off');

                % 设置坐标轴属性
                grid(app.UIAxes2, 'on');
                axis(app.UIAxes2, 'equal');  % 保证x轴和y轴比例相同
                xlabel(app.UIAxes2, '实部(Re)', 'FontSize', 10);
                ylabel(app.UIAxes2, '虚部(Im)', 'FontSize', 10);
                title(app.UIAxes2, '奈奎斯特图', 'FontSize', 12);

                % 添加图例
                legend_items = {'奈奎斯特曲线', '负实轴', '临界点(-1,0)'};
                if ~isinf(Gm_dB) && ~isinf(Wcg)
                    legend_items{end+1} = '增益裕度点';
                end
                if ~isinf(Pm) && ~isinf(Wcp)
                    legend_items{end+1} = '相位裕度点';
                end
                legend_items{end+1} = '单位圆';

                legend(app.UIAxes2, legend_items, 'Location', 'best', 'FontSize', 8);

                % ============ 更新状态信息 ============
                if isinf(Gm_dB) && isinf(Pm)
                    app.StatusLabel.Text = '奈奎斯特图绘制完成（系统非常稳定）';
                elseif isinf(Gm_dB)
                    app.StatusLabel.Text = sprintf('奈奎斯特图绘制完成（增益裕度无穷大，相位裕度: %.2f°）', Pm);
                elseif isinf(Pm)
                    app.StatusLabel.Text = sprintf('奈奎斯特图绘制完成（增益裕度: %.2f dB，相位裕度无穷大）', Gm_dB);
                else
                    app.StatusLabel.Text = sprintf('奈奎斯特图绘制完成（增益裕度: %.2f dB @ %.2f rad/s，相位裕度: %.2f° @ %.2f rad/s）', ...
                        Gm_dB, Wcg, Pm, Wcp);
                end

            catch ME
                app.StatusLabel.Text = sprintf('错误：%s', ME.message);
                msgbox(sprintf('发生错误：%s', ME.message), '错误', 'error');
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            try
                % ===== 使用总 App 的共用系统 =====
                if isempty(app.sys_tf)
                    app.StatusLabel.Text = '错误：请先创建系统';
                    return;
                end
                sys = app.sys_tf;

                app.StatusLabel.Text = '正在绘制单位阶跃响应...';
                drawnow;

                % ============ 计算单位阶跃响应 ============
                % 使用step函数计算阶跃响应
                [y, t] = step(sys);

                % ============ 计算时域性能指标 ============
                % 稳态值（取最后10%的数据平均值）
                n = length(y);
                steady_state_value = mean(y(floor(0.9*n):end));

                % 峰值和超调量
                [peak_value, peak_index] = max(y);
                overshoot_percent = 0;
                if steady_state_value > 0
                    overshoot_percent = 100 * (peak_value - steady_state_value) / steady_state_value;
                end

                % 上升时间（10%到90%）
                rise_time_10 = find(y >= 0.1*steady_state_value, 1, 'first');
                rise_time_90 = find(y >= 0.9*steady_state_value, 1, 'first');
                rise_time = 0;
                if ~isempty(rise_time_10) && ~isempty(rise_time_90)
                    rise_time = t(rise_time_90) - t(rise_time_10);
                end

                % 峰值时间
                peak_time = 0;
                if ~isempty(peak_index)
                    peak_time = t(peak_index);
                end

                % 调节时间（2%和5%准则）
                settling_time_2 = 0;
                settling_time_5 = 0;

                for i = 1:n
                    if all(abs(y(i:end) - steady_state_value) <= 0.02*abs(steady_state_value))
                        settling_time_2 = t(i);
                        break;
                    end
                end

                for i = 1:n
                    if all(abs(y(i:end) - steady_state_value) <= 0.05*abs(steady_state_value))
                        settling_time_5 = t(i);
                        break;
                    end
                end

                % ============ 绘制阶跃响应图到UIAxes3 ============
                cla(app.UIAxes3);

                % 绘制阶跃响应曲线
                plot(app.UIAxes3, t, y, 'b-', 'LineWidth', 2);
                hold(app.UIAxes3, 'on');

                % 标记稳态值线
                plot(app.UIAxes3, [t(1), t(end)], [steady_state_value, steady_state_value], ...
                    'r--', 'LineWidth', 1.5);

                % 标记峰值点
                if ~isempty(peak_index)
                    plot(app.UIAxes3, peak_time, peak_value, 'ro', ...
                        'MarkerSize', 10, 'MarkerFaceColor', 'r');
                    text(app.UIAxes3, peak_time, peak_value, ...
                        sprintf('峰值: %.3f @ %.3fs', peak_value, peak_time), ...
                        'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', ...
                        'FontSize', 8, 'Color', 'r');
                end

                % 标记上升时间点
                if rise_time_10 && rise_time_90
                    plot(app.UIAxes3, t(rise_time_10), y(rise_time_10), 'g^', ...
                        'MarkerSize', 8, 'MarkerFaceColor', 'g');
                    plot(app.UIAxes3, t(rise_time_90), y(rise_time_90), 'g^', ...
                        'MarkerSize', 8, 'MarkerFaceColor', 'g');
                    text(app.UIAxes3, t(rise_time_10), y(rise_time_10), ...
                        sprintf('10%%: %.3fs', t(rise_time_10)), ...
                        'VerticalAlignment', 'bottom', 'FontSize', 7, 'Color', 'g');
                    text(app.UIAxes3, t(rise_time_90), y(rise_time_90), ...
                        sprintf('90%%: %.3fs', t(rise_time_90)), ...
                        'VerticalAlignment', 'bottom', 'FontSize', 7, 'Color', 'g');
                end

                % 标记调节时间点
                if settling_time_2 > 0
                    plot(app.UIAxes3, settling_time_2, ...
                        steady_state_value + 0.02*steady_state_value, 'm*', 'MarkerSize', 10);
                    text(app.UIAxes3, settling_time_2, ...
                        steady_state_value + 0.02*steady_state_value, ...
                        sprintf('调节时间(2%%): %.3fs', settling_time_2), ...
                        'VerticalAlignment', 'bottom', 'FontSize', 7, 'Color', 'm');
                end

                if settling_time_5 > 0
                    plot(app.UIAxes3, settling_time_5, ...
                        steady_state_value + 0.05*steady_state_value, 'c*', 'MarkerSize', 10);
                    text(app.UIAxes3, settling_time_5, ...
                        steady_state_value + 0.05*steady_state_value, ...
                        sprintf('调节时间(5%%): %.3fs', settling_time_5), ...
                        'VerticalAlignment', 'bottom', 'FontSize', 7, 'Color', 'c');
                end

                hold(app.UIAxes3, 'off');

                % 设置坐标轴属性
                grid(app.UIAxes3, 'on');
                xlabel(app.UIAxes3, '时间 (秒)', 'FontSize', 10);
                ylabel(app.UIAxes3, '幅值', 'FontSize', 10);

                % 根据系统特性设置合适的标题
                if any(real(pole(sys)) >= 0)  % 不稳定系统
                    title(app.UIAxes3, '单位阶跃响应 (系统不稳定)', 'FontSize', 12, 'Color', 'r');
                else
                    if overshoot_percent > 0
                        title_str = sprintf('单位阶跃响应 (超调量: %.1f%%)', overshoot_percent);
                        title(app.UIAxes3, title_str, 'FontSize', 12);
                    else
                        title(app.UIAxes3, '单位阶跃响应', 'FontSize', 12);
                    end
                end

                % 添加图例
                legend_items = {'阶跃响应', '稳态值'};
                if ~isempty(peak_index)
                    legend_items{end+1} = '峰值点';
                end
                if rise_time_10 && rise_time_90
                    legend_items{end+1} = '上升时间点';
                end
                if settling_time_2 > 0
                    legend_items{end+1} = '调节时间(2%)';
                end
                if settling_time_5 > 0
                    legend_items{end+1} = '调节时间(5%)';
                end

                legend(app.UIAxes3, legend_items, 'Location', 'best', 'FontSize', 8);

                % ============ 在状态栏显示性能指标 ============
                if overshoot_percent > 0
                    performance_str = sprintf('超调量: %.1f%%, 上升时间: %.3fs, 峰值时间: %.3fs', ...
                        overshoot_percent, rise_time, peak_time);
                else
                    performance_str = sprintf('无超调, 上升时间: %.3fs', rise_time);
                end

                if settling_time_2 > 0
                    performance_str = sprintf('%s, 调节时间(2%%): %.3fs', performance_str, settling_time_2);
                end

                app.StatusLabel.Text = sprintf('单位阶跃响应绘制完成。稳态值: %.3f, %s', ...
                    steady_state_value, performance_str);

            catch ME
                app.StatusLabel.Text = sprintf('错误：%s', ME.message);
                msgbox(sprintf('发生错误：%s', ME.message), '错误', 'error');
            end
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            app.EditField_4.Value = '';
            app.EditField_3.Value = '';
        end

        % Button pushed function: UnitStepResponseButton
        function UnitStepResponseButtonPushed(app, event)
            try
                % ===== 使用总 App 的共用系统 =====
                if isempty(app.sys_tf)
                    app.StatusLabel.Text = '错误：请先创建系统';
                    return;
                end
                sys = app.sys_tf;
                simTime=str2num(app.SimulationTimeEditField.Value);
                
                [y,t]=step(sys,simTime);
                plot(app.UIAxes4,t,y,'b-','LineWidth',1.5);
                grid(app.UIAxes4,'on');
                S=stepinfo(sys);
                app.RiseTimeEditField.Value=num2str(S.RiseTime,'%.3f');
                app.PeakTimeEditField.Value=num2str(S.PeakTime,'%.3f');
                app.OvershootingEditField.Value=num2str(S.Overshoot,'%.2f');
                app.SettlingTimeEditField.Value=num2str(S.SettlingTime,'%.3f');
             catch ME
                 uialert(app.TimeDomainAnalysisofTimeInvariantSystemUIFigure,ME.message,'Failed Analysis');
             end
        end

        % Button pushed function: UnitImpulseResponseButton
        function UnitImpulseResponseButtonPushed(app, event)
            try
                % ===== 使用总 App 的共用系统 =====
                if isempty(app.sys_tf)
                    app.StatusLabel.Text = '错误：请先创建系统';
                    return;
                end
                sys = app.sys_tf;
                simTime=str2num(app.SimulationTimeEditField.Value);
                [y,t]=impulse(sys,simTime);
                plot(app.UIAxes3_2,t,y,'g-','LineWidth',1.5);
                grid(app.UIAxes3_2,'on');
             catch ME
                 uialert(app.TimeDomainAnalysisofTimeInvariantSystemUIFigure,ME.message,'Failed Analysis');
             end
        end

        % Button pushed function: UnitRampResponseButton
        function UnitRampResponseButtonPushed(app, event)
            try
                if isempty(app.sys_tf)
                    app.StatusLabel.Text = '错误：请先创建系统';
                    return;
                end
                sys = app.sys_tf;
                simTime=str2num(app.SimulationTimeEditField.Value);
        
                [y,t]=step(sys,simTime);
                ramp=cumtrapz(t,y);
                plot(app.UIAxes2_2,t,ramp,'r-','LineWidth',1.5);
                grid(app.UIAxes2_2,'on');

             catch ME
                 uialert(app.TimeDomainAnalysisofTimeInvariantSystemUIFigure,ME.message,'Failed Analysis');
             end

        end

        % Button pushed function: UnitStepResponseButton_2
        function UnitStepResponseButton_2Pushed(app, event)
            try        
                k = app.kEditField.Value;
                num_str = app.EditField.Value;
                den_str = app.EditField_2.Value;
                app.StatusLabel2.Text = '正在处理...';
                drawnow;
                if isempty(num_str) || isempty(den_str)
                    app.StatusLabel2.Text = '错误：请输入分子和分母系数';
                    return;
                end
                try
                    k_coeff = str2num(k)
                    num_coeff = str2num(num_str); 
                    den_coeff = str2num(den_str); 
                catch
                    app.StatusLabel2.Text = '错误：系数格式不正确';
                    return;
                end
                if isempty(num_coeff) || isempty(den_coeff)
                    app.StatusLabel2.Text = '错误：无法解析系数向量';
                    return;
                end
                num_coeff = k * num_coeff;
                sys = tf(num_coeff, den_coeff);
                [y, t] = step(sys);
                plot(app.UIAxes2_4, t, y, 'b-', 'LineWidth', 2);
                grid(app.UIAxes2_4, 'on'); 
                app.StatusLabel2.Text = '阶跃响应绘制完成';
            catch ME
                uialert(app.UIFigure, sprintf('计算错误: %s', ME.message), '计算失败');
            end
        end

        % Button pushed function: UnitRampResponseButton_2
        function UnitRampResponseButton_2Pushed(app, event)
            try
                k = app.kEditField.Value;
                num_str = app.EditField.Value;
                den_str = app.EditField_2.Value;
                app.StatusLabel2.Text = '正在处理...';
                drawnow;
                if isempty(num_str) || isempty(den_str)
                    app.StatusLabel2.Text = '错误：请输入分子和分母系数';
                    return;
                end
                try
                    k_coeff = str2num(k)
                    num_coeff = str2num(num_str); 
                    den_coeff = str2num(den_str); 
                catch
                    app.StatusLabel2.Text = '错误：系数格式不正确';
                    return;
                end
                if isempty(num_coeff) || isempty(den_coeff)
                    app.StatusLabel2.Text = '错误：无法解析系数向量';
                    return;
                end
                sys = tf(num_coeff, den_coeff);
                rlocus(app.UIAxes2_5, sys);
                grid(app.UIAxes2_5, 'on');
                if k_coeff > 0
                    hold(app.UIAxes2_5, 'on');
                        closed_loop_poles = rlocus(sys, k_coeff);
                    plot(app.UIAxes2, real(closed_loop_poles), imag(closed_loop_poles),'ro', 'MarkerSize', 8, 'LineWidth', 2,'DisplayName', sprintf('k=%.2f时的极点', k_coeff));
                    legend(app.UIAxes2_5, 'show');
                    hold(app.UIAxes2_5, 'off');
                end
                app.StatusLabel2.Text = '根轨迹绘制完成';
            catch ME
                app.StatusLabel2.Text = sprintf('错误：%s', ME.message);
                msgbox(sprintf('发生错误：%s', ME.message), '错误', 'error');
            end        
        end

        % Button pushed function: UnitImpulseResponseButton_2
        function UnitImpulseResponseButton_2Pushed(app, event)
            try        
                k = app.kEditField.Value;
                num_str = app.EditField.Value;
                den_str = app.EditField_2.Value;
                app.StatusLabel2.Text = '正在处理...';
                drawnow;
                if isempty(num_str) || isempty(den_str)
                    app.StatusLabel2.Text = '错误：请输入分子和分母系数';
                    return;
                end
                try
                    k_coeff = str2num(k)
                    num_coeff = str2num(num_str); 
                    den_coeff = str2num(den_str); 
                catch
                    app.StatusLabel2.Text = '错误：系数格式不正确';
                    return;
                end
                if isempty(num_coeff) || isempty(den_coeff)
                    app.StatusLabel2.Text = '错误：无法解析系数向量';
                    return;
                end
                num_coeff = k * num_coeff;
                numh = [1];
                denh = [1];
                [num_coeff den_coeff] = feedback(num_coeff,den_coeff,numh,denh);
                sys = tf(num_coeff, den_coeff);
                [y, t] = step(sys);
                plot(app.UIAxes2_3, t, y, 'b-', 'LineWidth', 2);
                grid(app.UIAxes2_3, 'on'); 
                app.StatusLabel2.Text = '阶跃响应绘制完成';
            catch ME
                uialert(app.UIFigure, sprintf('计算错误: %s', ME.message), '计算失败');
            end
        end

        % Button pushed function: UnitImpulseResponseButton_3
        function UnitImpulseResponseButton_3Pushed(app, event)
            try
                k = app.kEditField.Value;
                num_str = app.EditField.Value;
                den_str = app.EditField_2.Value;
                app.StatusLabel2.Text = '正在处理...';
                drawnow;
                if isempty(num_str) || isempty(den_str)
                    app.StatusLabel2.Text = '错误：请输入分子和分母系数';
                    return;
                end
                try
                    k_coeff = str2num(k)
                    num_coeff = str2num(num_str); 
                    den_coeff = str2num(den_str); 
                catch
                    app.StatusLabel2.Text = '错误：系数格式不正确';
                    return;
                end
                if isempty(num_coeff) || isempty(den_coeff)
                    app.StatusLabel2.Text = '错误：无法解析系数向量';
                    return;
                end
                numh = [1];
                denh = [1];
                [num_coeff den_coeff] = feedback(num_coeff,den_coeff,numh,denh);
                sys = tf(num_coeff, den_coeff);
                rlocus(app.UIAxes3_3, sys);
                grid(app.UIAxes3_3, 'on');
                if k_coeff > 0
                    hold(app.UIAxes3_3, 'on');
                        closed_loop_poles = rlocus(sys, k_coeff);
                    plot(app.UIAxes3_3, real(closed_loop_poles), imag(closed_loop_poles),'ro', 'MarkerSize', 8, 'LineWidth', 2,'DisplayName', sprintf('k=%.2f时的极点', k_coeff));
                    legend(app.UIAxes3_3, 'show');
                    hold(app.UIAxes3_3, 'off');
                end
                app.StatusLabel2.Text = '根轨迹绘制完成';
            catch ME
                app.StatusLabel2.Text = sprintf('错误：%s', ME.message);
                msgbox(sprintf('发生错误：%s', ME.message), '错误', 'error');
            end        
        end

        % Button pushed function: UnitImpulseResponseButton_4
        function UnitImpulseResponseButton_4Pushed(app, event)
            try 
                num = str2num(app.EditField.Value);
                den = str2num(app.EditField_2.Value);
                if isempty(num) || isempty(den) || ~isvector(num) || ~isvector(den)
                    uialert(app.LeadandLagCompensators, 'Invalid numerator or denominator. Use MATLAB vector format like [1 2 3].', 'Input Error');
                    return;
                end
                G = tf(num, den);
            
                PMd = str2double(app.TargetPMEditField.Value);
            
                if ~(isnumeric(PMd) && isscalar(PMd) && PMd>0 && PMd<180)
                    uialert(app.LeadandLagCompensators, 'PM must be a scalar in (0,180).', 'Input Error');
                    return;
                end
            
                wc_des = str2double(app.TargetWcEditField.Value);
                if ~(isnumeric(wc_des) && isscalar(wc_des) && wc_des>0)
                    uialert(app.LeadandLagCompensators, 'Wc must be a positive scalar.', 'Input Error');
                    return;
                end
            
                %  margin
                [Gm, Pm, Wcg, Wcp] = margin(G);
                disp(['Pm = ', num2str(Pm)]);
                disp(['Wcp = ', num2str(Wcp)]);
                
                if ~isfinite(Pm) || ~isfinite(Wcp)
                    uialert(app.LeadandLagCompensators, 'Cannot compute margin (Pm or Wcp invalid). Check plant stability or try different plant.', 'Computation Error');
                    return;
                end
            
                % 计算相位超前弧度
                phi_m = deg2rad(PMd - Pm + 5);   % +5° safety
                disp(['phi_m (deg) = ', num2str(rad2deg(phi_m))]);
                if ~(isnumeric(phi_m) && phi_m>0 && phi_m < pi/2)
                    uialert(app.LeadandLagCompensators, 'Requested phase lead out of practical range. Choose PM closer to current PM.', 'Design Error');
                    return;
                end
            
                % 计算alpha, tau, z, p 
                alpha = (1 - sin(phi_m)) / (1 + sin(phi_m));
                disp(['alpha = ', num2str(alpha)]);
                if ~(isfinite(alpha) && alpha>0 && alpha<1)
                    uialert(app.LeadandLagCompensators, 'Alpha calculation failed. Adjust PM or safety margin.', 'Design Error');
                    return;
                end
            
                tau = 1 / (wc_des * sqrt(alpha));
                if ~(isfinite(tau) && tau>0)
                    uialert(app.LeadandLagCompensators, 'Tau calculation invalid. Check Wc and alpha.', 'Design Error');
                    return;
                end
            
                z = 1 / tau;
                p = 1 / (alpha * tau);
            
                % 
                Gc0 = tf([1 z], [1 p]);
            
                % 取幅值
                magG = squeeze(bode(G, wc_des));        %  1x1xN -> squeeze -> scalar
                magGc0 = squeeze(bode(Gc0, wc_des));
                magG = real(magG); magGc0 = real(magGc0);   % 
                if numel(magG) > 1, magG = magG(1); end
                if numel(magGc0) > 1, magGc0 = magGc0(1); end
            
                if isempty(magG) || isempty(magGc0) || ~isfinite(magG) || ~isfinite(magGc0) || magG==0 || magGc0==0
                    uialert(app.LeadandLagCompensators, 'Magnitude at desired frequency invalid. Try a different Wc.', 'Computation Error');
                    return;
                end
            
                % 计算 Kc 
                Kc = 1 / (magG * magGc0);
                if ~isfinite(Kc) || abs(Kc) > 1e6
                    uialert(app.LeadandLagCompensators, 'Computed Kc invalid or too large. Adjust design parameters.', 'Design Error');
                    return;
                end
            
                % 校正器与闭环
                Gc = Kc * Gc0;
                Gol_new = series(Gc, G);
                T_new = feedback(Gol_new, 1);
                T_old = feedback(G, 1);
            
                % output
                outGc = sprintf('%.3f*(s+%.3f)/(s+%.3f)', Kc, z, p);
                outZ  = sprintf('%.3f', z);
                outP  = sprintf('%.3f', p);
                outKc = sprintf('%.3f', Kc);
                app.GcEditField.Value = outGc;
                app.ZEditField.Value  = outZ;
                app.PEditField.Value  = outP;
                app.KcEditField.Value = outKc;
            
                % 新相位裕度与交叉频率
                [Gm_new, Pm_new, Wcg_new, Wcp_new] = margin(Gol_new);
                if isfinite(Pm_new)
                    outPM = sprintf('%.2f°', Pm_new);
                else
                    outPM = 'N/A';
                end
                if isfinite(Wcp_new)
                    outWc = sprintf('%.2f rad/s', Wcp_new);
                else
                    outWc = 'N/A';
                end
            
                if isprop(app, 'PMLabel') && isprop(app.PMEditField, 'Text')
                    app.PMEditField.Value = outPM;
                    app.WcEditField.Value = outWc;
                else
                    if isprop(app, 'PMEditField'), app.PMEditField.Value = outPM; end
                    if isprop(app, 'WcEditField'), app.WcEditField.Value = outWc; end
                end
            
                
                % Bode
                cla(app.MagnitudeAxes);
                cla(app.PhaseAxes);
                % 
                set(app.MagnitudeAxes, 'XTickMode','auto', 'YTickMode','auto', 'XScale','log');
                set(app.PhaseAxes, 'XTickMode','auto', 'YTickMode','auto', 'XScale','log');


                [~,~,~,WcG] = margin(G);
                [~,~,~,WcC] = margin(Gol_new);
                if ~isnan(WcG) || ~isnan(WcC)
                    Wc = max([WcG WcC]);  
                    wmin = max(1e-3, Wc/100);
                    wmax = max(1e-1, Wc*100);
                else
                    wmin = 1e-2;
                    wmax = 1e2;
                end
                w = logspace(log10(wmin), log10(wmax), 800); 
                w = w(:);  
            
                [magG, phaseG]   = bode(G, w);
                [magGc, phaseGc] = bode(Gol_new, w);
            
                % 压成列向量
                magG   = squeeze(magG(:));
                phaseG = squeeze(phaseG(:));
                magGc   = squeeze(magGc(:));
                phaseGc = squeeze(phaseGc(:));
            
                % 转 dB
                magG_dB  = 20*log10(magG);
                magGc_dB = 20*log10(magGc);
            
                % 幅频轴
                cla(app.MagnitudeAxes);
                set(app.MagnitudeAxes, 'NextPlot','replacechildren'); 
                hold(app.MagnitudeAxes, 'on');
                plot(app.MagnitudeAxes, w, magG_dB,  'b', 'LineWidth', 1.2);
                plot(app.MagnitudeAxes, w, magGc_dB, 'r', 'LineWidth', 1.2);
                hold(app.MagnitudeAxes, 'off');
                grid(app.MagnitudeAxes, 'on');
                legend(app.MagnitudeAxes, 'Original', 'Compensated', 'Location','best');
                xlabel(app.MagnitudeAxes, 'Frequency (rad/s)');
                ylabel(app.MagnitudeAxes, 'Magnitude (dB)');
                xlim(app.MagnitudeAxes, [min(w) max(w)]);
            
                % 相频轴
                cla(app.PhaseAxes);
                set(app.PhaseAxes, 'NextPlot','replacechildren');
                hold(app.PhaseAxes, 'on');
                plot(app.PhaseAxes, w, phaseG,  'b', 'LineWidth', 1.2);
                plot(app.PhaseAxes, w, phaseGc, 'r', 'LineWidth', 1.2);
                hold(app.PhaseAxes, 'off');
                grid(app.PhaseAxes, 'on');
                legend(app.PhaseAxes, 'Original', 'Compensated', 'Location','best');
                xlabel(app.PhaseAxes, 'Frequency (rad/s)');
                ylabel(app.PhaseAxes, 'Phase (deg)');
                xlim(app.PhaseAxes, [min(w) max(w)]);
            
                % Step 
                cla(app.StepAxes);
                set(app.StepAxes, 'NextPlot','replacechildren'); % 防叠加
            
                if isprop(app, 'SimTimeEditField')
                    t_end = str2double(app.SimTimeEditField.Value);
                    if isnan(t_end) || t_end <= 0, t_end = 10; end
                else
                    t_end = 10;
                end
                t = linspace(0, t_end, 2000);
                y_old = step(T_old, t);
                y_new = step(T_new, t);
            
                plot(app.StepAxes, t, y_old, 'b', 'LineWidth', 1.2);
                hold(app.StepAxes, 'on');
                plot(app.StepAxes, t, y_new, 'r', 'LineWidth', 1.2);
                hold(app.StepAxes, 'off');
                xlim(app.StepAxes, [0 t_end]);
                xlabel(app.StepAxes, 'Time (s)');
                ylabel(app.StepAxes, 'Amplitude');
                legend(app.StepAxes, 'Original', 'Compensated', 'Location','best');
                grid(app.StepAxes, 'on');
            
                % 自动刻度
                if     t_end <= 10,  stepSize = 1;
                elseif t_end <= 50,  stepSize = 5;
                elseif t_end <= 200, stepSize = 20;
                else                 stepSize = 50;
                end
                xticks(app.StepAxes, 0:stepSize:t_end);
            end
        end

        % Button pushed function: UnitImpulseResponseButton_5
        function UnitImpulseResponseButton_5Pushed(app, event)
            try
                % 获取传递函数
                num = str2num(app.EditField.Value);
                den = str2num(app.EditField_2.Value);
                if isempty(num) || isempty(den) || ~isvector(num) || ~isvector(den)
                    uialert(app.LeadandLagCompensators, 'Invalid numerator or denominator. Use MATLAB vector format like [1 2 3].', 'Input Error');
                    return;
                end
                G = tf(num, den);
                
                % 检查系统稳定性
                poles = pole(G);
                if any(real(poles) > 0)
                    uialert(app.LeadandLagCompensators, 'System is unstable! Lag compensation is not appropriate.', 'Stability Error');
                    return;
                end
                
                % 获取期望的稳态误差
                ess_des = str2double(app.TargetEssEditField.Value);
                if isnan(ess_des) || ess_des <= 0 || ess_des >= 1
                    uialert(app.LeadandLagCompensators, 'Target steady-state error must be between 0 and 1.', 'Input Error');
                    return;
                end
                
                % ========== 计算当前系统的稳态误差 ==========
                % 检查系统类型
                [numG, denG] = tfdata(G, 'v');
                
                % 计算位置常数Kp
                Kp_current = dcgain(G);
                disp(['Current Kp = ', num2str(Kp_current)]);
                
                if ~isfinite(Kp_current)
                    % 系统有积分环节，Type 1或更高
                    current_ess = 0;
                    disp('System has integrator(s), current steady-state error = 0');
                elseif Kp_current <= 0
                    uialert(app.LeadandLagCompensators, 'System DC gain is non-positive. Cannot design lag compensator.', 'Computation Error');
                    return;
                else
                    current_ess = 1 / (1 + Kp_current);
                    disp(['Current steady-state error = ', num2str(current_ess)]);
                end
                
                % ========== 检查目标可行性 ==========
                if ess_des >= current_ess
                    msg = sprintf(['Current steady-state error: %.4f\n' ...
                                 'Target steady-state error: %.4f\n' ...
                                 'Target is not better than current. Lag compensation not required.'], ...
                                 current_ess, ess_des);
                    uialert(app.LeadandLagCompensators, msg, 'Design Notice');
                    return;
                end
                
                % ========== 计算目标Kp和beta ==========
                if ess_des == 0
                    Kp_des = Inf;  % 零稳态误差需要无限大增益
                    beta = Inf;
                else
                    Kp_des = (1 - ess_des) / ess_des;
                    if Kp_current == 0
                        beta = Inf;
                    else
                        beta = Kp_des / Kp_current;
                    end
                end
                
                disp(['Target Kp = ', num2str(Kp_des)]);
                disp(['Required beta = ', num2str(beta)]);
                
                % ========== 检查beta的合理性 ==========
                if beta <= 1
                    msg = sprintf(['Beta (%.2f) <= 1. This means:\n' ...
                                 'Target Kp (%.2f) <= Current Kp (%.2f)\n' ...
                                 'Lag compensation not required.'], ...
                                 beta, Kp_des, Kp_current);
                    uialert(app.LeadandLagCompensators, msg, 'Design Notice');
                    return;
                end
                
                if beta > 1000
                    msg = sprintf(['Required beta (%.2f) is too large (>1000).\n' ...
                                 'Current Kp: %.4f\n' ...
                                 'Target Kp: %.4f\n' ...
                                 'Consider using PI controller instead.'], ...
                                 beta, Kp_current, Kp_des);
                    uialert(app.LeadandLagCompensators, msg, 'Design Warning');
                    return;
                end
                
                % ========== 滞后补偿器设计 ==========
                % 获取当前系统的交叉频率
                [~, Pm, ~, Wcp] = margin(G);
                disp(['Current crossover frequency Wcp = ', num2str(Wcp), ' rad/s']);
                disp(['Current phase margin Pm = ', num2str(Pm), '°']);
                
                % 如果Wcp无效，使用带宽估计
                if isnan(Wcp) || Wcp <= 1e-3
                    [mag, ~, w] = bode(G);
                    mag = squeeze(mag);
                    mag_db = 20*log10(mag);
                    % 找到-3dB带宽
                    idx = find(mag_db <= -3, 1);
                    if ~isempty(idx)
                        Wcp = w(idx);
                        disp(['Using -3dB bandwidth as Wcp: ', num2str(Wcp), ' rad/s']);
                    else
                        Wcp = 1; % 默认值
                        disp(['Using default Wcp: ', num2str(Wcp), ' rad/s']);
                    end
                end
                
                % 设计滞后补偿器：零点放在交叉频率的1/10处
                z = Wcp / 10;
                
                % 确保零点不过于接近原点
                if z < 0.01
                    z_original = z;
                    z = 0.01;
                    msg = sprintf(['Zero frequency adjusted from %.4f to %.4f rad/s\n' ...
                                  'to avoid numerical issues.'], z_original, z);
                    uialert(app.LeadandLagCompensators, msg, 'Design Adjustment');
                end
                
                disp(['Compensator zero z = ', num2str(z), ' rad/s']);
                
                % 计算极点
                p = z / beta;
                
                % 确保极点不为零且不过于接近零点
                if p < 0.001
                    p_original = p;
                    p = 0.001;
                    beta_actual = z / p;
                    msg = sprintf(['Pole frequency adjusted from %.6f to %.4f rad/s\n' ...
                                  'Actual beta achieved: %.2f (target: %.2f)'], ...
                                  p_original, p, beta_actual, beta);
                    uialert(app.LeadandLagCompensators, msg, 'Design Limitation');
                end
                
                % 确保极点小于零点
                if p >= z
                    p = z * 0.1;  % 强制极点为零点的1/10
                    beta_actual = z / p;
                    msg = sprintf(['Pole frequency adjusted to ensure p < z\n' ...
                                  'Actual beta achieved: %.2f (target: %.2f)'], ...
                                  beta_actual, beta);
                    uialert(app.LeadandLagCompensators, msg, 'Design Adjustment');
                end
                
                disp(['Compensator pole p = ', num2str(p), ' rad/s']);
                disp(['Actual beta = z/p = ', num2str(z/p)]);
                
                % ========== 补偿器实现 ==========
                % 基本补偿器
                Gc0 = tf([1 z], [1 p]);
                
                % 计算增益Kc，使在交叉频率处总增益为0dB
                [mag_G, ~] = bode(G, Wcp);
                mag_G = squeeze(mag_G(1));
                
                [mag_Gc0, ~] = bode(Gc0, Wcp);
                mag_Gc0 = squeeze(mag_Gc0(1));
                
                if mag_G == 0 || mag_Gc0 == 0 || ~isfinite(mag_G) || ~isfinite(mag_Gc0)
                    Kc = 1;
                    disp('Warning: Using default Kc = 1 due to magnitude calculation issue');
                else
                    Kc = 1 / (mag_G * mag_Gc0);
                end
                
                disp(['Initial Kc = ', num2str(Kc)]);
                
                % 限制增益范围
                if Kc > 1000
                    Kc = 1000;
                    disp('Kc limited to 1000');
                elseif Kc < 0.001
                    Kc = 0.001;
                    disp('Kc limited to 0.001');
                end
                
                % 完整补偿器
                Gc = Kc * Gc0;
                disp(['Final compensator: Gc = ', num2str(Kc), ' * (s+', num2str(z), ')/(s+', num2str(p), ')']);
                
                % ========== 验证设计 ==========
                % 开环系统
                Gol_new = series(Gc, G);
                
                % 检查稳定性
                poles_new = pole(Gol_new);
                if any(real(poles_new) > 0)
                    uialert(app.LeadandLagCompensators, 'Compensated system is unstable!', 'Stability Error');
                    return;
                end
                
                % 闭环系统
                T_new = feedback(Gol_new, 1);
                T_old = feedback(G, 1);
                
                % 计算实际达到的稳态误差
                Kp_new = dcgain(Gol_new);
                if isfinite(Kp_new)
                    ess_actual = 1 / (1 + Kp_new);
                else
                    ess_actual = 0;
                end
                
                disp(['New Kp = ', num2str(Kp_new)]);
                disp(['Actual steady-state error achieved = ', num2str(ess_actual)]);
                
                % ========== 输出结果 ==========
                app.GcEditField.Value = sprintf('%.3f*(s+%.3f)/(s+%.3f)', Kc, z, p);
                app.ZEditField.Value  = sprintf('%.3f', z);
                app.PEditField.Value  = sprintf('%.3f', p);
                app.KcEditField.Value = sprintf('%.3f', Kc);
                
                % 计算新系统的相位裕度和交叉频率
                [~, Pm_new, ~, Wcp_new] = margin(Gol_new);
                if isfinite(Pm_new)
                    app.PMEditField.Value = sprintf('%.2f°', Pm_new);
                else
                    app.PMEditField.Value = 'N/A';
                end
                if isfinite(Wcp_new)
                    app.WcEditField.Value = sprintf('%.2f rad/s', Wcp_new);
                else
                    app.WcEditField.Value = 'N/A';
                end
                
                % 显示设计摘要
                disp('=== Lag Compensator Design Summary ===');
                fprintf('Current steady-state error: %.4f\n', current_ess);
                fprintf('Target steady-state error: %.4f\n', ess_des);
                fprintf('Current Kp: %.4f\n', Kp_current);
                fprintf('Target Kp: %.4f\n', Kp_des);
                fprintf('Required beta: %.4f\n', beta);
                fprintf('Compensator zero: %.4f rad/s\n', z);
                fprintf('Compensator pole: %.4f rad/s\n', p);
                fprintf('Actual beta achieved: %.4f\n', z/p);
                fprintf('Compensator gain Kc: %.4f\n', Kc);
                fprintf('New Kp: %.4f\n', Kp_new);
                fprintf('New steady-state error: %.4f\n', ess_actual);
                fprintf('New phase margin: %.2f°\n', Pm_new);
                fprintf('New crossover frequency: %.4f rad/s\n', Wcp_new);
                
                % ========== 绘制Bode图 ==========
                cla(app.MagnitudeAxes);
                cla(app.PhaseAxes);
                
                % 确定频率范围
                [~,~,~,WcG] = margin(G);
                [~,~,~,WcC] = margin(Gol_new);
                if ~isnan(WcG) || ~isnan(WcC)
                    Wc = max([WcG WcC]);  
                    wmin = max(1e-3, Wc/100);
                    wmax = max(1e-1, Wc*100);
                else
                    wmin = 1e-2;
                    wmax = 1e2;
                end
                w = logspace(log10(wmin), log10(wmax), 800); 
                w = w(:);  
            
                % 计算频率响应
                [magG, phaseG]   = bode(G, w);
                [magGc, phaseGc] = bode(Gol_new, w);
            
                % 转换为向量
                magG   = squeeze(magG(:));
                phaseG = squeeze(phaseG(:));
                magGc   = squeeze(magGc(:));
                phaseGc = squeeze(phaseGc(:));
            
                % 转换为dB
                magG_dB  = 20*log10(magG);
                magGc_dB = 20*log10(magGc);
            
                % 绘制幅频特性
                cla(app.MagnitudeAxes);
                set(app.MagnitudeAxes, 'NextPlot','replacechildren'); 
                hold(app.MagnitudeAxes, 'on');
                plot(app.MagnitudeAxes, w, magG_dB,  'b', 'LineWidth', 1.5);
                plot(app.MagnitudeAxes, w, magGc_dB, 'r--', 'LineWidth', 1.5);
                hold(app.MagnitudeAxes, 'off');
                grid(app.MagnitudeAxes, 'on');
                legend(app.MagnitudeAxes, 'Original', 'Compensated', 'Location','best');
                xlabel(app.MagnitudeAxes, 'Frequency (rad/s)');
                ylabel(app.MagnitudeAxes, 'Magnitude (dB)');
                title(app.MagnitudeAxes, 'Bode Magnitude Plot');
                set(app.MagnitudeAxes, 'XScale','log');
                xlim(app.MagnitudeAxes, [min(w) max(w)]);
            
                % 绘制相频特性
                cla(app.PhaseAxes);
                set(app.PhaseAxes, 'NextPlot','replacechildren');
                hold(app.PhaseAxes, 'on');
                plot(app.PhaseAxes, w, phaseG,  'b', 'LineWidth', 1.5);
                plot(app.PhaseAxes, w, phaseGc, 'r--', 'LineWidth', 1.5);
                hold(app.PhaseAxes, 'off');
                grid(app.PhaseAxes, 'on');
                legend(app.PhaseAxes, 'Original', 'Compensated', 'Location','best');
                xlabel(app.PhaseAxes, 'Frequency (rad/s)');
                ylabel(app.PhaseAxes, 'Phase (deg)');
                title(app.PhaseAxes, 'Bode Phase Plot');
                set(app.PhaseAxes, 'XScale','log');
                xlim(app.PhaseAxes, [min(w) max(w)]);
            
                % ========== 绘制阶跃响应 ==========
                cla(app.StepAxes);
                set(app.StepAxes, 'NextPlot','replacechildren');
                
                if isprop(app, 'SimTimeEditField')
                    t_end = str2double(app.SimTimeEditField.Value);
                    if isnan(t_end) || t_end <= 0, t_end = 10; end
                else
                    t_end = 10;
                end
                t = linspace(0, t_end, 2000);
                y_old = step(T_old, t);
                y_new = step(T_new, t);
            
                plot(app.StepAxes, t, y_old, 'b', 'LineWidth', 1.5);
                hold(app.StepAxes, 'on');
                plot(app.StepAxes, t, y_new, 'r--', 'LineWidth', 1.5);
                hold(app.StepAxes, 'off');
                xlim(app.StepAxes, [0 t_end]);
                xlabel(app.StepAxes, 'Time (s)');
                ylabel(app.StepAxes, 'Amplitude');
                title(app.StepAxes, 'Step Response');
                legend(app.StepAxes, 'Original', 'Compensated', 'Location','best');
                grid(app.StepAxes, 'on');
            
                % 设置时间刻度
                if t_end <= 10
                    stepSize = 1;
                elseif t_end <= 50
                    stepSize = 5;
                elseif t_end <= 200
                    stepSize = 20;
                else
                    stepSize = 50;
                end
                xticks(app.StepAxes, 0:stepSize:t_end);
                
            catch ME
                uialert(app.UIFigure, ...
                    sprintf('Error in lag compensator design:\n%s\n\nStack trace:\n%s', ...
                            ME.message, getReport(ME, 'extended')), ...
                    'Runtime Error');
                return;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '线性系统分析';
            app.Panel_2 = uipanel(app.Panel);
            app.Panel_2.Title = '公共参数';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '分子';
            app.EditField = uieditfield(app.Panel_2, 'text');
            app.EditField_2Label = uilabel(app.Panel_2);
            app.EditField_2Label.Text = '分母';
            app.EditField_2 = uieditfield(app.Panel_2, 'text');
            app.CreateButton = uibutton(app.Panel_2, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = '创建系统';
            app.CleanButton = uibutton(app.Panel_2, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.CleanButton.Text = '清除';
            app.TabGroup = uitabgroup(app.Panel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '频域分析';
            app.Panel_control = uipanel(app.Tab);
            app.Panel_control.Title = '控制面板';
            app.Button = uibutton(app.Panel_control, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '奈奎斯特图';
            app.bodeButton = uibutton(app.Panel_control, 'push');
            app.bodeButton.ButtonPushedFcn = createCallbackFcn(app, @bodeButtonPushed, true);
            app.bodeButton.Text = '伯德图';
            app.Button_3 = uibutton(app.Panel_control, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '单位跃迁响应';
            app.ClearButton = uibutton(app.Panel_control, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = '清除图表';
            app.Panel_output = uipanel(app.Tab);
            app.Panel_output.Title = '输出面板';
            app.Panel_5 = uipanel(app.Panel_output);
            app.Panel_5.Title = '伯德图';
            app.UIAxes = uiaxes(app.Panel_5);
            xlabel(app.UIAxes, '频率 (rad/s)')
            ylabel(app.UIAxes, '幅度 (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.Panel_5);
            xlabel(app.UIAxes_2, '频率 (rad/s)')
            ylabel(app.UIAxes_2, '相位 (度)')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel_bode = uipanel(app.Panel_5);
            app.Panel_bode.Title = '频率重要指标';
            app.Label_2 = uilabel(app.Panel_bode);
            app.Label_2.Text = '幅值裕度= ';
            app.EditField_3 = uieditfield(app.Panel_bode, 'text');
            app.EditField_4Label = uilabel(app.Panel_bode);
            app.EditField_4Label.Text = '相位裕度= ';
            app.EditField_4 = uieditfield(app.Panel_bode, 'text');
            app.StatusLabel = uilabel(app.Panel_5);
            app.StatusLabel.Text = '就绪';
            app.Panel_6 = uipanel(app.Panel_output);
            app.Panel_6.Title = '奈奎斯特图与单位阶跃响应';
            app.UIAxes2 = uiaxes(app.Panel_6);
            title(app.UIAxes2, '奈奎斯特图')
            xlabel(app.UIAxes2, '实部')
            ylabel(app.UIAxes2, '虚部')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.Panel_6);
            title(app.UIAxes3, '单位跃迁响应')
            xlabel(app.UIAxes3, '时间（秒）')
            ylabel(app.UIAxes3, '响应')
            zlabel(app.UIAxes3, 'Z')
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '时域分析';
            app.Panel_control_2 = uipanel(app.Tab_2);
            app.Panel_control_2.Title = '控制面板';
            app.UnitStepResponseButton = uibutton(app.Panel_control_2, 'push');
            app.UnitStepResponseButton.ButtonPushedFcn = createCallbackFcn(app, @UnitStepResponseButtonPushed, true);
            app.UnitStepResponseButton.Text = '单位阶跃响应';
            app.UnitRampResponseButton = uibutton(app.Panel_control_2, 'push');
            app.UnitRampResponseButton.ButtonPushedFcn = createCallbackFcn(app, @UnitRampResponseButtonPushed, true);
            app.UnitRampResponseButton.Text = '单位斜坡响应';
            app.UnitImpulseResponseButton = uibutton(app.Panel_control_2, 'push');
            app.UnitImpulseResponseButton.ButtonPushedFcn = createCallbackFcn(app, @UnitImpulseResponseButtonPushed, true);
            app.UnitImpulseResponseButton.Text = '单位脉冲响应';
            app.Label_3 = uilabel(app.Panel_control_2);
            app.Label_3.Text = '仿真时间';
            app.SimulationTimeEditField = uieditfield(app.Panel_control_2, 'text');
            app.Panel_output_2 = uipanel(app.Tab_2);
            app.Panel_output_2.Title = '输出面板';
            app.Panel_7 = uipanel(app.Panel_output_2);
            app.Panel_7.Title = '单位阶跃响应';
            app.UIAxes4 = uiaxes(app.Panel_7);
            title(app.UIAxes4, '单位阶跃响应曲线')
            xlabel(app.UIAxes4, '时间（秒）')
            ylabel(app.UIAxes4, '幅度 (dB)')
            zlabel(app.UIAxes4, 'Z')
            app.Panel_bode_2 = uipanel(app.Panel_7);
            app.Panel_bode_2.Title = '单位阶跃响应性能指标';
            app.Label_4 = uilabel(app.Panel_bode_2);
            app.Label_4.Text = '上升时间';
            app.RiseTimeEditField = uieditfield(app.Panel_bode_2, 'text');
            app.Label_5 = uilabel(app.Panel_bode_2);
            app.Label_5.Text = '高峰时间';
            app.PeakTimeEditField = uieditfield(app.Panel_bode_2, 'text');
            app.Label_6 = uilabel(app.Panel_bode_2);
            app.Label_6.Text = '超调量';
            app.OvershootingEditField = uieditfield(app.Panel_bode_2, 'text');
            app.Label_7 = uilabel(app.Panel_bode_2);
            app.Label_7.Text = '调节时间';
            app.SettlingTimeEditField = uieditfield(app.Panel_bode_2, 'text');
            app.Panel_8 = uipanel(app.Panel_output_2);
            app.Panel_8.Title = '单位斜坡响应与单位脉冲响应';
            app.UIAxes2_2 = uiaxes(app.Panel_8);
            title(app.UIAxes2_2, '单位斜坡响应')
            xlabel(app.UIAxes2_2, '时间（秒）')
            ylabel(app.UIAxes2_2, '幅度 (dB)')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes3_2 = uiaxes(app.Panel_8);
            title(app.UIAxes3_2, '单位脉冲响应')
            xlabel(app.UIAxes3_2, '时间（秒）')
            ylabel(app.UIAxes3_2, '幅度 (dB)')
            zlabel(app.UIAxes3_2, 'Z')
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '根轨迹';
            app.Panel_output_3 = uipanel(app.Tab_3);
            app.Panel_output_3.Title = '输出面板';
            app.Panel_9 = uipanel(app.Panel_output_3);
            app.Panel_9.Title = '开环';
            app.UIAxes2_4 = uiaxes(app.Panel_9);
            title(app.UIAxes2_4, '单位阶跃响应')
            xlabel(app.UIAxes2_4, '时间（秒）')
            ylabel(app.UIAxes2_4, '幅度 (dB)')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_5 = uiaxes(app.Panel_9);
            title(app.UIAxes2_5, '根轨迹')
            xlabel(app.UIAxes2_5, '实部')
            ylabel(app.UIAxes2_5, '虚部')
            zlabel(app.UIAxes2_5, 'Z')
            app.StatusLabel2 = uilabel(app.Panel_9);
            app.StatusLabel2.Text = '就绪';
            app.Panel_10 = uipanel(app.Panel_output_3);
            app.Panel_10.Title = '闭环';
            app.UIAxes2_3 = uiaxes(app.Panel_10);
            title(app.UIAxes2_3, '单位阶跃响应')
            xlabel(app.UIAxes2_3, '时间（秒）')
            ylabel(app.UIAxes2_3, '幅度 (dB)')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes3_3 = uiaxes(app.Panel_10);
            title(app.UIAxes3_3, '根轨迹')
            xlabel(app.UIAxes3_3, '实部')
            ylabel(app.UIAxes3_3, '虚部')
            zlabel(app.UIAxes3_3, 'Z')
            app.Panel_control_3 = uipanel(app.Tab_3);
            app.Panel_control_3.Title = '控制面板';
            app.UnitStepResponseButton_2 = uibutton(app.Panel_control_3, 'push');
            app.UnitStepResponseButton_2.ButtonPushedFcn = createCallbackFcn(app, @UnitStepResponseButton_2Pushed, true);
            app.UnitStepResponseButton_2.Text = '开环单位阶跃响应';
            app.UnitRampResponseButton_2 = uibutton(app.Panel_control_3, 'push');
            app.UnitRampResponseButton_2.ButtonPushedFcn = createCallbackFcn(app, @UnitRampResponseButton_2Pushed, true);
            app.UnitRampResponseButton_2.Text = '开环根轨迹';
            app.UnitImpulseResponseButton_2 = uibutton(app.Panel_control_3, 'push');
            app.UnitImpulseResponseButton_2.ButtonPushedFcn = createCallbackFcn(app, @UnitImpulseResponseButton_2Pushed, true);
            app.UnitImpulseResponseButton_2.Text = '闭环单位阶跃响应';
            app.Label_8 = uilabel(app.Panel_control_3);
            app.Label_8.Text = '开环增益k=';
            app.kEditField = uieditfield(app.Panel_control_3, 'text');
            app.UnitImpulseResponseButton_3 = uibutton(app.Panel_control_3, 'push');
            app.UnitImpulseResponseButton_3.ButtonPushedFcn = createCallbackFcn(app, @UnitImpulseResponseButton_3Pushed, true);
            app.UnitImpulseResponseButton_3.Text = '闭环根轨迹';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '系统矫正';
            app.Panel_control_4 = uipanel(app.Tab_4);
            app.Panel_control_4.Title = '控制面板';
            app.UnitImpulseResponseButton_4 = uibutton(app.Panel_control_4, 'push');
            app.UnitImpulseResponseButton_4.ButtonPushedFcn = createCallbackFcn(app, @UnitImpulseResponseButton_4Pushed, true);
            app.UnitImpulseResponseButton_4.Text = '超前设计';
            app.EditField_7Label = uilabel(app.Panel_control_4);
            app.EditField_7Label.Text = '仿真时间';
            app.SimTimeEditField = uieditfield(app.Panel_control_4, 'text');
            app.UnitImpulseResponseButton_5 = uibutton(app.Panel_control_4, 'push');
            app.UnitImpulseResponseButton_5.ButtonPushedFcn = createCallbackFcn(app, @UnitImpulseResponseButton_5Pushed, true);
            app.UnitImpulseResponseButton_5.Text = '滞后设计';
            app.EditField_5Label = uilabel(app.Panel_control_4);
            app.EditField_5Label.Text = '目标相位裕度';
            app.TargetPMEditField = uieditfield(app.Panel_control_4, 'text');
            app.EditField_6Label = uilabel(app.Panel_control_4);
            app.EditField_6Label.Text = '目标交叉频率';
            app.TargetWcEditField = uieditfield(app.Panel_control_4, 'text');
            app.Label_15 = uilabel(app.Panel_control_4);
            app.Label_15.Text = '目标稳态误差';
            app.TargetEssEditField = uieditfield(app.Panel_control_4, 'text');
            app.Panel_output_4 = uipanel(app.Tab_4);
            app.Panel_output_4.Title = '输出面板';
            app.MagnitudeAxes = uiaxes(app.Panel_output_4);
            title(app.MagnitudeAxes, '幅值图')
            xlabel(app.MagnitudeAxes, '频率(rad/s)')
            ylabel(app.MagnitudeAxes, '幅度 (dB)')
            zlabel(app.MagnitudeAxes, 'Z')
            app.PhaseAxes = uiaxes(app.Panel_output_4);
            title(app.PhaseAxes, '相位图')
            xlabel(app.PhaseAxes, '频率(rad/s)')
            ylabel(app.PhaseAxes, '相位(度) ')
            zlabel(app.PhaseAxes, 'Z')
            app.StepAxes = uiaxes(app.Panel_output_4);
            title(app.StepAxes, '阶跃响应')
            xlabel(app.StepAxes, '时间（秒）')
            ylabel(app.StepAxes, '幅度 (dB)')
            zlabel(app.StepAxes, 'Z')
            app.Panel_bode_3 = uipanel(app.Panel_output_4);
            app.Panel_bode_3.Title = ' 补偿结果';
            app.Label_9 = uilabel(app.Panel_bode_3);
            app.Label_9.Text = 'Gc';
            app.GcEditField = uieditfield(app.Panel_bode_3, 'text');
            app.Label_10 = uilabel(app.Panel_bode_3);
            app.Label_10.Text = 'Z';
            app.ZEditField = uieditfield(app.Panel_bode_3, 'text');
            app.Label_11 = uilabel(app.Panel_bode_3);
            app.Label_11.Text = 'P';
            app.PEditField = uieditfield(app.Panel_bode_3, 'text');
            app.Label_12 = uilabel(app.Panel_bode_3);
            app.Label_12.Text = 'Kc';
            app.KcEditField = uieditfield(app.Panel_bode_3, 'text');
            app.Label_13 = uilabel(app.Panel_bode_3);
            app.Label_13.Text = 'PM';
            app.PMEditField = uieditfield(app.Panel_bode_3, 'text');
            app.Label_14 = uilabel(app.Panel_bode_3);
            app.Label_14.Text = 'Wc';
            app.WcEditField = uieditfield(app.Panel_bode_3, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LSA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end